classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SimulationMenu             matlab.ui.container.Menu
        NewsimulationMenu          matlab.ui.container.Menu
        SavesimulationMenu         matlab.ui.container.Menu
        LoadsimulationMenu         matlab.ui.container.Menu
        RequestsMenu_2             matlab.ui.container.Menu
        ListMenu                   matlab.ui.container.Menu
        LoadMenu                   matlab.ui.container.Menu
        VisualizeMenu              matlab.ui.container.Menu
        DepotsMenu                 matlab.ui.container.Menu
        ListMenu_2                 matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        Image                      matlab.ui.control.Image
        GridLayout3                matlab.ui.container.GridLayout
        SimulateButton             matlab.ui.control.Button
        SolverPanel                matlab.ui.container.Panel
        DropDown                   matlab.ui.control.DropDown
        DepotsvehiclesPanel        matlab.ui.container.Panel
        VehiclesEditField          matlab.ui.control.NumericEditField
        VehiclesEditFieldLabel     matlab.ui.control.Label
        DepotsEditField            matlab.ui.control.NumericEditField
        DepotsEditFieldLabel       matlab.ui.control.Label
        AddnewButton_2             matlab.ui.control.Button
        RequestsPanel              matlab.ui.container.Panel
        DropoffendEditField        matlab.ui.control.EditField
        DropoffendEditFieldLabel   matlab.ui.control.Label
        PickupstartEditField       matlab.ui.control.EditField
        PickupstartEditFieldLabel  matlab.ui.control.Label
        AddnewButton               matlab.ui.control.Button
        MapsidelengthPanel         matlab.ui.container.Panel
        Spinner                    matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        uiState; %State of the UI. 1 - basic, 2 - add request, 3 - add depot
        
        graph; 
        coordsNodesImg;
        coordsArcsImg;
        
        baseImg; % an underlying layer under the top layers
        img; % reference to the image that is displayed in the main Image component
        resolution; % global parameter
        padding; % global parameter
        nodeRadius; % radius of all the nodes in the images
          
        requestsExist; % indicates that the simulation contains requests
        pickupAdded; % indicate that the request already has a pickup location
        requests; % array of requests
        
        depotsExist; % indicates that the simulation contains depots
        depots; % array of depots
        
        vehiclesExist; % indicates that the simulation contains vehicles
        vehicles; % array of vehicles
        
        solver;
    end
    
    methods (Access = private)           
        function updateImg(app)
            %1. Create emptyn white image
            app.baseImg = uint8(ones(app.resolution,app.resolution,3)*255);
            
            %2. Draw all lines
            for i = 1:app.graph.nArcs
                app.baseImg = drawLine(app.baseImg, ...
                                  app.coordsArcsImg(i,1), ...
                                  app.coordsArcsImg(i,2), ...
                                  app.coordsArcsImg(i,3), ...
                                  app.coordsArcsImg(i,4), ...
                                  [0,0,0], ...
                                  3);
            end
            
            %3. Draw all nodes
            for i = 1:app.graph.nNodes
                app.img = drawDot(app.baseImg, ...
                                  app.coordsNodesImg(i,1), ...
                                  app.coordsNodesImg(i,2), ...
                                  app.nodeRadius, ...
                                  [0,0,0], ...
                                  1, ...
                                  [0,0,0], ...
                                  -1);
            end
            
            %Update the image
            app.Image.ImageSource = app.baseImg;
        end
        
        function updateImgTopLayer(app, newPickupNodeId)
            % Add a top layer to the baseImg with the request and depot nodes
            % params:
            %   - newPickup: the nodeID of the pickup node of the new
            %   request
            
            [pickUps, dropOffs, depotsLocal] = getRequestDepotnodes(app);
            pickUps = [pickUps; newPickupNodeId];
            
            app.img = app.baseImg;
            
            for i = 1:app.graph.nNodes
                % Draw depots
                if(ismember(i, depotsLocal))
                    
                end
                
                % Draw nodes that are both pickup and dropoff places
                if(ismember(i, pickUps) &&ismember(i,dropOffs))
                    
                end
                
                % Draw pickups
                if(ismember(i, pickUps))
                    
                end
                
                % Draw dropoffs
                if(ismember(i,dropOffs))
                    
                end
            end
            
            %Check if the newPickupNodeId parameter is valid
            if(newPickupNodeId > 0 && newPickupNodeId <= app.graph.nNodes)
                app.img = drawDot(app.baseImg, ...
                                  app.coordsNodesImg(newPickupNodeId,1), ...
                                  app.coordsNodesImg(newPickupNodeId,2), ...
                                  app.nodeRadius, ...
                                  [0,128,0], ...
                                  1, ...
                                  [0,128,0], ...
                                  -1);
            end
            
            % Draw request dots, if there are any
            if(app.requestsExist)
                
            end
            
            % Draw depots, if there are any
            if(app.depotsExist)
                
            end
            
            %Update the image
            app.Image.ImageSource = app.baseImg;
        end
        
        function convertToImgCoords(app)
            max_x = max(app.graph.nodeCoords(:,1));
            max_y = max(app.graph.nodeCoords(:,1));
            
            % The padding
            offset = app.padding;
            % Scaling value along the x axis
            multip_x = (app.resolution - 2 * app.padding)/max_x;
            % Scaling value along the y axis
            multip_y = (app.resolution - 2 * app.padding)/max_y;
            
            %Coordinates of nodes
            app.coordsNodesImg = zeros(app.graph.nNodes,2);
            for i = 1:app.graph.nNodes
                app.coordsNodesImg(i,1) = round(offset + multip_x * app.graph.nodeCoords(i,1));
                app.coordsNodesImg(i,2) = round(offset + multip_y * app.graph.nodeCoords(i,2));
            end
            
            % Init the global radius parameter
            app.nodeRadius = floor((multip_x+multip_y)/20);            
            if(app.nodeRadius > app.padding)
               app.nodeRadius = app.padding - 1;
            end
            
            %Start and end coordinates of arcs
            app.coordsArcsImg = zeros(app.graph.nNodes,4);
            for i = 1:app.graph.nArcs
                app.coordsArcsImg(i,1) = round(offset + multip_x * app.graph.arcCoords(i,1));
                app.coordsArcsImg(i,2) = round(offset + multip_y * app.graph.arcCoords(i,2));
                app.coordsArcsImg(i,3) = round(offset + multip_x * app.graph.arcCoords(i,3));
                app.coordsArcsImg(i,4) = round(offset + multip_y * app.graph.arcCoords(i,4));
            end
        end
        
        function handleImgClick(app, coordX, coordY)
            %Declare local persistent variable
            persistent newRequest
            
            switch(app.uiState)
                case 1 % STATE_BASIC
                    
                case 2 % STATE_ADD_REQUEST_PICKUP
                    nodeId = app.findNodeByCoord(coordX, coordY);
                    
                    %Check if node is not already a pickup point
                    [pickUps, ~, ~] = getRequestDepotnodes(app);
                    if(ismember(nodeId,pickUps))
                        %node is already a pickup node -> do nothing
                    else
                        newRequest = darpRequest;
                    
                        %Add pickup node
                        newRequest.pickUpNodeId = nodeId;
                        if(~app.requestsExist)
                            newRequest.requestId = 1;
                        else
                            newRequest.requestId = size(app.requests,1);
                        end
                    end
                    
                case 3 % STATE_ADD_REQUEST_DROPOFF
                    nodeId = app.findNodeByCoord(coordX, coordY);
                    
                    %Check if node is not already a pickup point
                    [~, dropOffs, ~] = getRequestDepotnodes(app);
                    if(ismember(nodeId,dropOffs))
                        %node is already a dropoff node -> do nothing
                    else
                        %Add dropoff nodeid
                        newRequest.dropOffNodeId = nodeId;
                        
                        %TODO add the pickup and dropoff times
                    end
                    
                case 4 % STATE_ADD_DEPOT
                    
            end
        end
        
        function id = findNodeByCoord(app, coordXIn, coordYIn)
            % Returns the id of the node based on the coordinates
            id = -1;
            for i = 1:app.graph.nNodes
                if(app.checkClickInNode(i,coordXIn, coordYIn))
                    id = i;
                end
            end
        end
        
        function isIn = checkClickInNode(app, nodeId, coordXIn, coordYIn)
            xNodeImg = app.coordsNodesImg(nodeId, 1);
            yNodeImg = app.coordsNodesImg(nodeId, 2);
            if((xNodeImg - coordXIn)^2 + (yNodeImg - coordYIn)^2 <= app.nodeRadius^2)
                isIn = true;
            else
                isIn = false;
            end
        end
    
        function [pickUps, dropOffs, depots] = getRequestDepotnodes(app)
            % Iterate over the array of requests and depots. Returns the
            % nodeIds of pickup, dropoff and depot nodes           
            
            if(app.requestsExist)
                pickUpsTemp = zeros(size(app.requests,1),1);
                dropOffsTemp = zeros(size(app.requests,1),1);
                % Iterate over all requests
                for i = 1:size(app.requests,1)
                    pickUpsTemp(i) = app.requests(i).pickUpNodeId;
                    dropOffsTemp(i) = app.requests(i).dropOffNodeId;
                end
                pickUps = pickUpsTemp;
                dropOffs = dropOffsTemp;
            else
                pickUps = [0];
                dropOffs = [0];
            end
            
            if(app.depotsExist)
                depotsTemp = zeros(size(app.depots,1),1);
                %Iterate over all depots
                for i = 1:size(app.depots,1)
                    depotsTemp(i) = app.depots(i).nodeId;
                end
                depots = depotsTemp;
            else
                depots = [0];
            end
        end
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Instantiate a darpGraph with the given sideLength       
            app.graph = darpSquareGridGraph(app.Spinner.Value);
            % Init the image properties
            app.resolution = 2048;
            app.padding = 50;
            %Calculate the image coordinates
            app.convertToImgCoords();
            
            % Initialize the status flags
            app.requestsExist = false;
            app.depotsExist = false;
            app.vehiclesExist = false;
                        
            %Update the main image
            app.updateImg();
            
            %Set UI state
            app.uiState = 1;
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            %Update the grap
            app.graph = darpSquareGridGraph(app.Spinner.Value);
            %Update the image coordinates
            app.convertToImgCoords();
            %Update the main image
            app.updateImg();
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
           % Get the position of the image
            imgX = app.Image.Position(1);
            imgY = app.Image.Position(2);
            imgWidth = app.Image.Position(3);
            imgHeight = app.Image.Position(4);
  
            %The global coordinates of the click
            clickCoords = event.Source.CurrentPoint;
            
            % Handle rectangleness
            if(imgWidth > imgHeight)
                imgX = imgX + (imgWidth - imgHeight) / 2;
                %Check if the click occured inside the image
                if(clickCoords(1) > imgX && clickCoords(2) > imgY && ...
                    clickCoords(1) < imgX + imgHeight && ...
                    clickCoords(2) < imgY + imgHeight)
                    
                    clickXImg = floor((imgY + imgHeight - clickCoords(2))/imgHeight*app.resolution);
                    clickYImg = floor((clickCoords(1) - imgX)/imgHeight*app.resolution);
                    
                    fprintf("Img click: X,Y = %d,%d\n", clickXImg, clickYImg)
                    app.handleImgClick(clickXImg, clickYImg) 
                end
            else
                imgY = imgY + (imgHeight - imgWidth) / 2;  
                %Check if the click occured inside the image
                if(clickCoords(1) > imgX && clickCoords(2) > imgY && ...
                    clickCoords(1) < imgX + imgWidth && ...
                    clickCoords(2) < imgY + imgWidth)
                    
                    clickXImg = floor((imgY + imgWidth - clickCoords(2))/imgWidth*app.resolution);
                    clickYImg = floor((clickCoords(1) - imgX)/imgWidth*app.resolution);
                    
                    fprintf("Img click: X,Y = %d,%d\n", clickXImg, clickYImg)
                    app.handleImgClick(clickXImg, clickYImg)           
                end
            end
            
        end

        % Button pushed function: AddnewButton
        function AddnewButtonPushed(app, event)
            if(app.uiState == 1)
                app.uiState = 2; % set STATE_ADD_REQUEST
                app.RequestsPanel.BackgroundColor = '#FFB6B6'; %lightred
                app.RequestsPanel.FontWeight = 'bold';
                app.AddnewButton.Text = "Cancel";
            elseif(app.uiState == 2)
                app.uiState = 1; % set STATE_BASE
                app.RequestsPanel.BackgroundColor = [0.94,0.94,0.94]; %orginal grey
                app.RequestsPanel.FontWeight = 'normal';
                app.AddnewButton.Text = "Add new";
            end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            
            %Handle ESC button pressed when adding new requests or depots
            switch key
                case 'escape'
                    if(app.uiState == 2)
                        app.uiState = 1; % set STATE_ADD_REQUEST
                        app.RequestsPanel.BackgroundColor = [0.94,0.94,0.94]; %orginal grey
                        app.RequestsPanel.FontWeight = 'normal';
                        app.AddnewButton.Text = "Add new";
                    end
                otherwise
                    %Other key was pressed
            end
        end

        % Value changed function: PickupstartEditField
        function PickupstartEditFieldValueChanged(app, event)
            %If the text is incorrect 
            if(validateTimeInput(app.PickupstartEditField.Value))
                app.PickupstartEditField.Value = '00:00';
                app.PickupstartEditField.FontColor = 'red';
            end
        end

        % Value changing function: PickupstartEditField
        function PickupstartEditFieldValueChanging(app, event)
            app.PickupstartEditField.FontColor = 'black';
        end

        % Value changing function: DropoffendEditField
        function DropoffendEditFieldValueChanging(app, event)
            app.DropoffendEditField.FontColor = 'black';
        end

        % Value changed function: DropoffendEditField
        function DropoffendEditFieldValueChanged(app, event)
            %If the text is incorrect 
            if(validateTimeInput(app.PickupstartEditField.Value))
                app.DropoffendEditField.Value = '00:00';
                app.DropoffendEditField.FontColor = 'red';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.SimulationMenu = uimenu(app.UIFigure);
            app.SimulationMenu.Text = 'Simulation';
            app.NewsimulationMenu = uimenu(app.SimulationMenu);
            app.NewsimulationMenu.Text = 'New simulation';
            app.SavesimulationMenu = uimenu(app.SimulationMenu);
            app.SavesimulationMenu.Text = 'Save simulation';
            app.LoadsimulationMenu = uimenu(app.SimulationMenu);
            app.LoadsimulationMenu.Text = 'Load simulation';
            app.RequestsMenu_2 = uimenu(app.UIFigure);
            app.RequestsMenu_2.Text = 'Requests';
            app.ListMenu = uimenu(app.RequestsMenu_2);
            app.ListMenu.Text = 'List';
            app.LoadMenu = uimenu(app.RequestsMenu_2);
            app.LoadMenu.Text = 'Load';
            app.VisualizeMenu = uimenu(app.RequestsMenu_2);
            app.VisualizeMenu.Text = 'Visualize';
            app.DepotsMenu = uimenu(app.UIFigure);
            app.DepotsMenu.Text = 'Depots';
            app.ListMenu_2 = uimenu(app.DepotsMenu);
            app.ListMenu_2.Text = 'List';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.MapsidelengthPanel = uipanel(app.GridLayout3);
            app.MapsidelengthPanel.Title = 'Map side length';
            app.Spinner = uispinner(app.MapsidelengthPanel);
            app.Spinner.Limits = [3 10];
            app.Spinner.RoundFractionalValues = 'on';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 6;
            app.RequestsPanel = uipanel(app.GridLayout3);
            app.RequestsPanel.Title = 'Requests';
            app.AddnewButton = uibutton(app.RequestsPanel, 'push');
            app.AddnewButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewButtonPushed, true);
            app.AddnewButton.Text = 'Add new';
            app.PickupstartEditFieldLabel = uilabel(app.RequestsPanel);
            app.PickupstartEditFieldLabel.Text = 'Pickup start';
            app.PickupstartEditField = uieditfield(app.RequestsPanel, 'text');
            app.PickupstartEditField.ValueChangedFcn = createCallbackFcn(app, @PickupstartEditFieldValueChanged, true);
            app.PickupstartEditField.ValueChangingFcn = createCallbackFcn(app, @PickupstartEditFieldValueChanging, true);
            app.DropoffendEditFieldLabel = uilabel(app.RequestsPanel);
            app.DropoffendEditFieldLabel.Text = 'Dropoff end';
            app.DropoffendEditField = uieditfield(app.RequestsPanel, 'text');
            app.DropoffendEditField.ValueChangedFcn = createCallbackFcn(app, @DropoffendEditFieldValueChanged, true);
            app.DropoffendEditField.ValueChangingFcn = createCallbackFcn(app, @DropoffendEditFieldValueChanging, true);
            app.DepotsvehiclesPanel = uipanel(app.GridLayout3);
            app.DepotsvehiclesPanel.Title = 'Depots & vehicles';
            app.AddnewButton_2 = uibutton(app.DepotsvehiclesPanel, 'push');
            app.AddnewButton_2.Text = 'Add new';
            app.DepotsEditFieldLabel = uilabel(app.DepotsvehiclesPanel);
            app.DepotsEditFieldLabel.Text = 'Depots';
            app.DepotsEditField = uieditfield(app.DepotsvehiclesPanel, 'numeric');
            app.VehiclesEditFieldLabel = uilabel(app.DepotsvehiclesPanel);
            app.VehiclesEditFieldLabel.Text = 'Vehicles';
            app.VehiclesEditField = uieditfield(app.DepotsvehiclesPanel, 'numeric');
            app.SolverPanel = uipanel(app.GridLayout3);
            app.SolverPanel.Title = 'Solver';
            app.DropDown = uidropdown(app.SolverPanel);
            app.SimulateButton = uibutton(app.GridLayout3, 'push');
            app.SimulateButton.Text = 'Simulate';
            app.Image = uiimage(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end